classdef detectSaccades < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ArtifactDetectionPanel         matlab.ui.container.Panel
        NeighborThresField             matlab.ui.control.NumericEditField
        NeighborthresmsEditFieldLabel  matlab.ui.control.Label
        MaxArtifactDurationField       matlab.ui.control.NumericEditField
        MaxartifactdurationmsEditFieldLabel  matlab.ui.control.Label
        FolderField                    matlab.ui.control.EditField
        FolderButton                   matlab.ui.control.Button
        CalibrationPanel               matlab.ui.container.Panel
        CalibrationButtonGroup         matlab.ui.container.ButtonGroup
        UseCustomButton                matlab.ui.control.RadioButton
        UseCaliButton                  matlab.ui.control.RadioButton
        Custom2Field                   matlab.ui.control.NumericEditField
        CustomscaleCh2EditFieldLabel   matlab.ui.control.Label
        Custom1Field                   matlab.ui.control.NumericEditField
        CustomscaleCh1EditFieldLabel   matlab.ui.control.Label
        SaccadeDetectionPanel          matlab.ui.container.Panel
        EndField                       matlab.ui.control.NumericEditField
        EndsEditFieldLabel             matlab.ui.control.Label
        StartField                     matlab.ui.control.NumericEditField
        StartsEditFieldLabel           matlab.ui.control.Label
        OmitCheckBox                   matlab.ui.control.CheckBox
        VelocityThresFactorField       matlab.ui.control.NumericEditField
        VelocitythresfactorEditFieldLabel  matlab.ui.control.Label
        UseSaccadeCheckBox             matlab.ui.control.CheckBox
        DetectSaccadesButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        params % Params for saccade detection
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: CalibrationButtonGroup
        function CalibrationButtonGroupSelectionChanged(app, event)
            if app.UseCaliButton.Value
                app.Custom1Field.Enable = 'off';
                app.Custom2Field.Enable = 'off';
            else
                app.Custom1Field.Enable = 'on';
                app.Custom2Field.Enable = 'on';
            end                
        end

        % Button pushed function: DetectSaccadesButton
        function DetectSaccadesButtonPushed(app, event)
            app.params.isUsingCali = app.UseCaliButton.Value;
            app.params.customScaleCh1 = app.Custom1Field.Value;
            app.params.customScaleCh2 = app.Custom2Field.Value;
            app.params.isUsingSaccades = app.UseSaccadeCheckBox.Value;
            app.params.isRemovingSegment = app.OmitCheckBox.Value;
            app.params.removeTime = [app.StartField.Value, app.EndField.Value];
            app.params.velThresFactor = app.VelocityThresFactorField.Value;
            app.params.maxArtifactDuration = app.MaxArtifactDurationField.Value;
            app.params.neighborThres = app.NeighborThresField.Value;
            detectSaccades_APP(app.params);
        end

        % Value changed function: OmitCheckBox
        function OmitCheckBoxValueChanged(app, event)
            value = app.OmitCheckBox.Value;
            if value
                app.StartField.Enable = true;
                app.EndField.Enable = true;
            else
                app.StartField.Enable = false;
                app.EndField.Enable = false;
            end
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            folder = uigetdir(pwd);
            if folder
                app.FolderField.Value = folder;
                cd(folder);
                app.DetectSaccadesButton.Enable = true;
            end
        end

        % Value changed function: FolderField
        function FolderFieldValueChanged(app, event)
            folder = app.FolderField.Value;
            app.FolderField.Value = folder;
            try
                cd(folder);
                app.DetectSaccadesButton.Enable = true;
            catch 
                disp('Folder not found!');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaccadeDetectionPanel = uipanel(app.UIFigure);
            app.SaccadeDetectionPanel.Title = 'Saccade Detection';
            app.DetectSaccadesButton = uibutton(app.SaccadeDetectionPanel, 'push');
            app.DetectSaccadesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectSaccadesButtonPushed, true);
            app.DetectSaccadesButton.Enable = 'off';
            app.DetectSaccadesButton.Text = 'Detect Saccades';
            app.UseSaccadeCheckBox = uicheckbox(app.SaccadeDetectionPanel);
            app.UseSaccadeCheckBox.Tooltip = {'Use this to skip the saccade detection process'};
            app.UseSaccadeCheckBox.Text = 'Use saccades.mat file';
            app.VelocitythresfactorEditFieldLabel = uilabel(app.SaccadeDetectionPanel);
            app.VelocitythresfactorEditFieldLabel.Tooltip = {'Lower threshold means more things get detected as saccades'};
            app.VelocitythresfactorEditFieldLabel.Text = 'Velocity thres factor';
            app.VelocityThresFactorField = uieditfield(app.SaccadeDetectionPanel, 'numeric');
            app.VelocityThresFactorField.Limits = [0 Inf];
            app.VelocityThresFactorField.Value = 5;
            app.OmitCheckBox = uicheckbox(app.SaccadeDetectionPanel);
            app.OmitCheckBox.ValueChangedFcn = createCallbackFcn(app, @OmitCheckBoxValueChanged, true);
            app.OmitCheckBox.Text = 'Omit a time segment';
            app.StartsEditFieldLabel = uilabel(app.SaccadeDetectionPanel);
            app.StartsEditFieldLabel.Tooltip = {'Timestamp in seconds'};
            app.StartsEditFieldLabel.Text = 'Start (s)';
            app.StartField = uieditfield(app.SaccadeDetectionPanel, 'numeric');
            app.StartField.Limits = [0 Inf];
            app.StartField.Enable = 'off';
            app.StartField.Tooltip = {''};
            app.EndsEditFieldLabel = uilabel(app.SaccadeDetectionPanel);
            app.EndsEditFieldLabel.Tooltip = {'Timestamp in seconds'};
            app.EndsEditFieldLabel.Text = 'End (s)';
            app.EndField = uieditfield(app.SaccadeDetectionPanel, 'numeric');
            app.EndField.Limits = [0 Inf];
            app.EndField.Enable = 'off';
            app.EndField.Tooltip = {''};
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Tooltip = {''};
            app.CalibrationPanel.Title = 'Calibration';
            app.CustomscaleCh1EditFieldLabel = uilabel(app.CalibrationPanel);
            app.CustomscaleCh1EditFieldLabel.Text = 'Custom scaleCh1';
            app.Custom1Field = uieditfield(app.CalibrationPanel, 'numeric');
            app.Custom1Field.Enable = 'off';
            app.CustomscaleCh2EditFieldLabel = uilabel(app.CalibrationPanel);
            app.CustomscaleCh2EditFieldLabel.Text = 'Custom scaleCh2';
            app.Custom2Field = uieditfield(app.CalibrationPanel, 'numeric');
            app.Custom2Field.Enable = 'off';
            app.CalibrationButtonGroup = uibuttongroup(app.CalibrationPanel);
            app.CalibrationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalibrationButtonGroupSelectionChanged, true);
            app.UseCaliButton = uiradiobutton(app.CalibrationButtonGroup);
            app.UseCaliButton.Text = 'Use cali.mat file';
            app.UseCaliButton.Value = true;
            app.UseCustomButton = uiradiobutton(app.CalibrationButtonGroup);
            app.UseCustomButton.Tooltip = {'Typically, you set one channel to 0'};
            app.UseCustomButton.Text = 'Use custom scale factors';
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Tooltip = {'Make sure this folder contains a .smr file and (if relevant) a cali.mat file and saccades.mat file'};
            app.FolderButton.Text = 'Folder';
            app.FolderField = uieditfield(app.UIFigure, 'text');
            app.FolderField.ValueChangedFcn = createCallbackFcn(app, @FolderFieldValueChanged, true);
            app.ArtifactDetectionPanel = uipanel(app.UIFigure);
            app.ArtifactDetectionPanel.Title = 'Artifact Detection';
            app.MaxartifactdurationmsEditFieldLabel = uilabel(app.ArtifactDetectionPanel);
            app.MaxartifactdurationmsEditFieldLabel.Tooltip = {'A saccade will be marked as an artifact if the eye pos returns to the start pos within __ ms'};
            app.MaxartifactdurationmsEditFieldLabel.Text = 'Max artifact duration (ms)';
            app.MaxArtifactDurationField = uieditfield(app.ArtifactDetectionPanel, 'numeric');
            app.MaxArtifactDurationField.Limits = [0 Inf];
            app.MaxArtifactDurationField.Tooltip = {''};
            app.MaxArtifactDurationField.Value = 100;
            app.NeighborthresmsEditFieldLabel = uilabel(app.ArtifactDetectionPanel);
            app.NeighborthresmsEditFieldLabel.Tooltip = {'Run the artifact detection algorithm on saccades that are within __ ms of each other'};
            app.NeighborthresmsEditFieldLabel.Text = 'Neighbor thres (ms)';
            app.NeighborThresField = uieditfield(app.ArtifactDetectionPanel, 'numeric');
            app.NeighborThresField.Limits = [0 Inf];
            app.NeighborThresField.Tooltip = {''};
            app.NeighborThresField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detectSaccades

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end